classdef exp4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GammaLabel                      matlab.ui.control.Label
        MismatchLossEditField           matlab.ui.control.NumericEditField
        MismatchLossEditFieldLabel      matlab.ui.control.Label
        ReturnLossEditField             matlab.ui.control.NumericEditField
        ReturnLossEditFieldLabel        matlab.ui.control.Label
        VSWREditField                   matlab.ui.control.NumericEditField
        VSWREditFieldLabel              matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        ReflectionCoefficientEditField  matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
   

                % Read Reflection Coefficient
                Gamma = app.ReflectionCoefficientEditField.Value;

                % Check valid range
                if Gamma < 0 || Gamma > 1
                    uialert(app.UIFigure,...
                        'Reflection Coefficient must be between 0 and 1.',...
                        'Input Error');
                    return;
                end

                % Calculate VSWR
                if Gamma == 1
                    VSWR = Inf;
                else
                    VSWR = (1 + Gamma) / (1 - Gamma);
                end

                % Calculate Return Loss
                if Gamma == 0
                    RL = Inf;
                else
                    RL = -20 * log10(Gamma);
                end

                % Calculate Mismatch Loss
                if Gamma == 1
                    ML = Inf;
                else
                    ML = -10 * log10(1 - Gamma^2);
                end

                % Display Results
                app.VSWREditField.Value = round(VSWR,2);
                app.ReturnLossEditField.Value = round(RL,2);
                app.MismatchLossEditField.Value = round(ML,2);

              % Data for graphs
GammaPlot = 0:0.01:0.99;

VSWRPlot = (1 + GammaPlot)./(1 - GammaPlot);

RLPlot = -20*log10(GammaPlot);
RLPlot(1) = Inf;      % Gamma = 0

MLPlot = -10*log10(1 - GammaPlot.^2);

% -------- VSWR --------
plot(app.UIAxes,GammaPlot,VSWRPlot,'b','LineWidth',2)
grid(app.UIAxes,'on')
xlabel(app.UIAxes,'Reflection Coefficient (\Gamma)')
ylabel(app.UIAxes,'VSWR')
title(app.UIAxes,'VSWR Variation')

% -------- Return Loss --------
plot(app.UIAxes2,GammaPlot,RLPlot,'r','LineWidth',2)
grid(app.UIAxes2,'on')
xlabel(app.UIAxes2,'Reflection Coefficient (\Gamma)')
ylabel(app.UIAxes2,'Return Loss (dB)')
title(app.UIAxes2,'Return Loss Variation')

% -------- Mismatch Loss --------
plot(app.UIAxes3,GammaPlot,MLPlot,'g','LineWidth',2)
grid(app.UIAxes3,'on')
xlabel(app.UIAxes3,'Reflection Coefficient (\Gamma)')
ylabel(app.UIAxes3,'Mismatch Loss (dB)')
title(app.UIAxes3,'Mismatch Loss Variation')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'VSWR and Return Loss Analysis Tool';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Reflection Coefficient';
            app.ReflectionCoefficientEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.VSWREditFieldLabel = uilabel(app.UIFigure);
            app.VSWREditFieldLabel.Text = 'VSWR';
            app.VSWREditField = uieditfield(app.UIFigure, 'numeric');
            app.ReturnLossEditFieldLabel = uilabel(app.UIFigure);
            app.ReturnLossEditFieldLabel.Text = 'ReturnLoss';
            app.ReturnLossEditField = uieditfield(app.UIFigure, 'numeric');
            app.MismatchLossEditFieldLabel = uilabel(app.UIFigure);
            app.MismatchLossEditFieldLabel.Text = 'MismatchLoss';
            app.MismatchLossEditField = uieditfield(app.UIFigure, 'numeric');
            app.GammaLabel = uilabel(app.UIFigure);
            app.GammaLabel.Text = 'Gamma';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exp4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end