classdef exp9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ElementSpacingLabel         matlab.ui.control.Label
        PhaseshiftLabel             matlab.ui.control.Label
        NumberofElementLabel        matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        PlotRadiationPatternButton  matlab.ui.control.Button
        unitDropDown                matlab.ui.control.DropDown
        unitDropDownLabel           matlab.ui.control.Label
        FrequencyEditField          matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel     matlab.ui.control.Label
        ESEditField                 matlab.ui.control.NumericEditField
        ESEditFieldLabel            matlab.ui.control.Label
        PSEditField                 matlab.ui.control.NumericEditField
        PSEditFieldLabel            matlab.ui.control.Label
        NEditField                  matlab.ui.control.NumericEditField
        NEditFieldLabel             matlab.ui.control.Label
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotRadiationPatternButton
        function PlotRadiationPatternButtonPushed(app, event)
            % Read input values
            f = app.FrequencyEditField.Value;
            unit = app.unitDropDown.Value;

            N = round(app.NEditField.Value);
            d = app.ESEditField.Value;      % Spacing in meters
            beta_deg = app.PSEditField.Value;

            % Validate inputs
            if N < 2
                uialert(app.UIFigure,'Number of elements must be at least 2','Input Error');
                return;
            end

            if d <= 0
                uialert(app.UIFigure,'Element spacing must be greater than zero','Input Error');
                return;
            end

            if f <= 0
                uialert(app.UIFigure,'Frequency must be greater than zero','Input Error');
                return;
            end

            % Convert frequency to Hz
            switch unit
                case 'Hz'
                    fc = f;
                case 'kHz'
                    fc = f*1e3;
                case 'MHz'
                    fc = f*1e6;
                case 'GHz'
                    fc = f*1e9;
            end

            % Constants
            c = physconst('LightSpeed');
            lambda = c/fc;

            % Convert spacing from meters to wavelengths
            d_lambda = d/lambda;

            % Angle
            theta = -90:0.1:90;

            % Phase shift
            beta = deg2rad(beta_deg);

            % Psi
            psi = 2*pi*d_lambda*sind(theta) + beta;

            % Array Factor
            AF = zeros(size(theta));

            for n = 0:N-1
                AF = AF + exp(1j*n*psi);
            end

            % Normalize
            AF = abs(AF);
            AF = AF/max(AF);

            % Convert to dB
            AFdB = 20*log10(AF + eps);
            AFdB(AFdB < -40) = -40;

            %% Array Factor Plot
            cla(app.UIAxes)
            plot(app.UIAxes,theta,AFdB,'LineWidth',2)
            grid(app.UIAxes,'on')
            xlabel(app.UIAxes,'Angle (Degrees)')
            ylabel(app.UIAxes,'Normalized Array Factor (dB)')
            title(app.UIAxes,'Array Factor')
            xlim(app.UIAxes,[-90 90])
            ylim(app.UIAxes,[-40 0])

            %% Radiation Pattern
            cla(app.UIAxes2)
            plot(app.UIAxes2,theta,AF,'LineWidth',2)
            grid(app.UIAxes2,'on')
            xlabel(app.UIAxes2,'Angle (Degrees)')
            ylabel(app.UIAxes2,'Normalized Magnitude')
            title(app.UIAxes2,'Radiation Pattern')
            xlim(app.UIAxes2,[-90 90])
            ylim(app.UIAxes2,[0 1])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.PSEditFieldLabel = uilabel(app.UIFigure);
            app.PSEditFieldLabel.Text = 'PS';
            app.PSEditField = uieditfield(app.UIFigure, 'numeric');
            app.ESEditFieldLabel = uilabel(app.UIFigure);
            app.ESEditFieldLabel.Text = 'ES';
            app.ESEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.unitDropDownLabel = uilabel(app.UIFigure);
            app.unitDropDownLabel.Text = 'unit';
            app.unitDropDown = uidropdown(app.UIFigure);
            app.unitDropDown.Items = {'Hz', ' kHz', 'MHz', 'GHz'};
            app.unitDropDown.Value = 'Hz';
            app.PlotRadiationPatternButton = uibutton(app.UIFigure, 'push');
            app.PlotRadiationPatternButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRadiationPatternButtonPushed, true);
            app.PlotRadiationPatternButton.Text = 'Plot Radiation Pattern';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = ' Antenna Array Factor Visualization Tool';
            app.NumberofElementLabel = uilabel(app.UIFigure);
            app.NumberofElementLabel.Text = 'Number of Element ';
            app.PhaseshiftLabel = uilabel(app.UIFigure);
            app.PhaseshiftLabel.Text = 'Phase shift';
            app.ElementSpacingLabel = uilabel(app.UIFigure);
            app.ElementSpacingLabel.Text = 'Element Spacing ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exp9

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end